classdef PCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PilihGambarButton  matlab.ui.control.Button
        Area               matlab.ui.control.Label
        Major              matlab.ui.control.Label
        Minor              matlab.ui.control.Label
        Perimeter          matlab.ui.control.Label
        Eccentric          matlab.ui.control.Label
        AreaLabel          matlab.ui.control.Label
        MajorAxisLabel     matlab.ui.control.Label
        MinorAxisLabel     matlab.ui.control.Label
        PerimeterLabel     matlab.ui.control.Label
        EccentricityLabel  matlab.ui.control.Label
        KlasifikasiLabel   matlab.ui.control.Label
        Klasifikasi        matlab.ui.control.Label
        rgb                matlab.ui.control.UIAxes
        gray               matlab.ui.control.UIAxes
        bin                matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Model % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [num_train,txt,raw] = xlsread("E:\Huda Nitip\Tubes Pengcit\dataset_training.xlsx",1);
            [num_kelas,kelas,raw_kelas] = xlsread("dataset_training.xlsx",2); 
           model = fitcknn(num_train,kelas);
            model.Distance = 'euclidean';
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
              img= imread(fullfile(path,file), 'jpg');
              img = imresize(img,0.3125);
                SE1 = [0 1 0; 1 1 1; 0 1 0];
                SE2 = [1 0 1; 0 1 0; 1 0 1];
                SE3 = [1 1 1; 1 1 1; 1 1 1];
                gray = rgb2gray(img);
                bw = imbinarize(gray);
                bw = ~bw;
                dil = imdilate(bw,SE3);
                cc = bwconncomp(dil,4);
                num = 1;
                counter=0;
                while(cc.NumObjects~=1)
                    if(cc.NumObjects>1)
                        num = cc.NumObjects;
                        dil = imdilate(dil,SE2);
                        counter = counter+1;
                    end
                    cc = bwconncomp(dil,4);
                    
                end
                while(counter>0)
                    dil = imerode(dil,SE1);
                    counter = counter-1;
                end
                SE = strel('disk', 3, 0);
                op = imopen(dil, SE);
                clo = imclose(op, SE);
                daun = false(size(clo));
                daun(cc.PixelIdxList{1}) = true;
                daundata = regionprops(cc,"all");
                getFeature = daundata;
                data = [getFeature.Area getFeature.MajorAxisLength getFeature.MinorAxisLength getFeature.Perimeter getFeature.Eccentricity];
                hasil = predict(model,data);
                app.Area.Text = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(getFeature.Area);
                app.Major.Text = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(getFeature.MajorAxisLength);
                app.Minor.Text = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(getFeature.MinorAxisLength);
                app.Perimeter.Text = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(getFeature.Perimeter);
                app.Eccentric.Text = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(getFeature.Eccentricity);
                app.Klasifikasi.Text = hasil(1);
               
              ax = uiaxes(app.UIFigure,'Position',[0 270 300 400]);
              cx= uiaxes(app.UIFigure,'Position',[280 270 300 400]);
              bx= uiaxes(app.UIFigure,'Position',[550 270 300 400]);
             
              image(img,'Parent',ax);
              gray = repmat(im2uint8(gray),1,1,3);
              image(gray,'Parent',cx);
              rgb = repmat(im2uint8(clo),1,1,3);
              image(rgb,'Parent',bx);
              r = imhist(img);
              g = imhist(gray);
              b = imhist(rgb);
              plot(app.rgb,r,'-r');
              plot(app.gray,g,'-r');
              plot(app.bin,b,'-r');
              set(ax,'visible','off');
              set(bx,'visible','off');
              set(cx,'visible','off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PilihGambarButton = uibutton(app.UIFigure, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.Area = uilabel(app.UIFigure);
            app.Area.Text = '0';
            app.Major = uilabel(app.UIFigure);
            app.Major.Text = '0';
            app.Minor = uilabel(app.UIFigure);
            app.Minor.Text = '0';
            app.Perimeter = uilabel(app.UIFigure);
            app.Perimeter.Text = '0';
            app.Eccentric = uilabel(app.UIFigure);
            app.Eccentric.Text = '0';
            app.AreaLabel = uilabel(app.UIFigure);
            app.AreaLabel.Text = 'Area    :';
            app.MajorAxisLabel = uilabel(app.UIFigure);
            app.MajorAxisLabel.Text = 'Major Axis :';
            app.MinorAxisLabel = uilabel(app.UIFigure);
            app.MinorAxisLabel.Text = 'Minor Axis :';
            app.PerimeterLabel = uilabel(app.UIFigure);
            app.PerimeterLabel.Text = 'Perimeter :';
            app.EccentricityLabel = uilabel(app.UIFigure);
            app.EccentricityLabel.Text = 'Eccentricity :';
            app.KlasifikasiLabel = uilabel(app.UIFigure);
            app.KlasifikasiLabel.Text = 'Klasifikasi :';
            app.Klasifikasi = uilabel(app.UIFigure);
            app.Klasifikasi.Text = '0';
            app.rgb = uiaxes(app.UIFigure);
            title(app.rgb, 'RGB')
            xlabel(app.rgb, 'X')
            ylabel(app.rgb, 'Y')
            app.gray = uiaxes(app.UIFigure);
            title(app.gray, 'Grayscale')
            xlabel(app.gray, 'X')
            ylabel(app.gray, 'Y')
            app.bin = uiaxes(app.UIFigure);
            title(app.bin, 'Binary')
            xlabel(app.bin, 'X')
            ylabel(app.bin, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = PCD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end